classdef Aplicacion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        tabla                    matlab.ui.control.Table
        txtpunitario             matlab.ui.control.NumericEditField
        PunitarioEditFieldLabel  matlab.ui.control.Label
        txtpcosto                matlab.ui.control.NumericEditField
        PcostoEditFieldLabel     matlab.ui.control.Label
        txtcantidad              matlab.ui.control.NumericEditField
        CantidadEditFieldLabel   matlab.ui.control.Label
        EliminarButton           matlab.ui.control.Button
        AnularButton             matlab.ui.control.Button
        ModificarButton          matlab.ui.control.Button
        GuardarButton            matlab.ui.control.Button
    end

    properties (Access = private)
        idDato
    end

    methods (Access = private)
        function funcionLimpiar(app)
            app.tabla.Data         = Conexiondb();
            app.txtcantidad.Value  = 0;
            app.txtpcosto.Value    = 0;
            app.txtpunitario.Value = 0;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.tabla.Data = Conexiondb();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

           
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            cantidad = app.txtcantidad.Value;
            precioCosto = app.txtpcosto.Value;
            precioUnitario = app.txtpunitario.Value;
            guardarDato(cantidad, precioCosto, precioUnitario);
            app.funcionLimpiar();
            msgbox("Registro Ingresado!");
        end

        % Button pushed function: ModificarButton
        function ModificarButtonPushed(app, event)
            cantidad = app.txtcantidad.Value;
            precioCosto = app.txtpcosto.Value;
            precioUnitario = app.txtpunitario.Value;
            modificarDato(app.idDato, cantidad, precioCosto, precioUnitario);
            msgbox("Registro Modificado");
            app.funcionLimpiar();
        end

        % Button pushed function: AnularButton
        function AnularButtonPushed(app, event)
            anularDato(app.idDato);
            msgbox("Registro Anulado");
            app.funcionLimpiar();
        end

        % Button pushed function: EliminarButton
        function EliminarButtonPushed(app, event)
            eliminarDato(app.idDato);
            msgbox("Registro Eliminado");
            app.funcionLimpiar();
        end

        % Selection changed function: tabla
        function tablaSelectionChanged(app, event)
            selection = app.tabla.Selection;
            if ~isempty(selection)
            fila = selection(1);
            app.idDato             = app.tabla.Data{fila, 1};
            app.txtcantidad.Value  = app.tabla.Data{fila, 3};
            app.txtpcosto.Value    = app.tabla.Data{fila, 4};
            app.txtpunitario.Value = app.tabla.Data{fila, 5};
        end
            
        end

        % Cell edit callback: tabla
        function tablaCellEdit(app, event)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GuardarButton = uibutton(app.UIFigure, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.ModificarButton = uibutton(app.UIFigure, 'push');
            app.ModificarButton.ButtonPushedFcn = createCallbackFcn(app, @ModificarButtonPushed, true);
            app.ModificarButton.Text = 'Modificar';
            app.AnularButton = uibutton(app.UIFigure, 'push');
            app.AnularButton.ButtonPushedFcn = createCallbackFcn(app, @AnularButtonPushed, true);
            app.AnularButton.Text = 'Anular';
            app.EliminarButton = uibutton(app.UIFigure, 'push');
            app.EliminarButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarButtonPushed, true);
            app.EliminarButton.Text = 'Eliminar';
            app.CantidadEditFieldLabel = uilabel(app.UIFigure);
            app.CantidadEditFieldLabel.Text = 'Cantidad';
            app.txtcantidad = uieditfield(app.UIFigure, 'numeric');
            app.PcostoEditFieldLabel = uilabel(app.UIFigure);
            app.PcostoEditFieldLabel.Text = 'Pcosto';
            app.txtpcosto = uieditfield(app.UIFigure, 'numeric');
            app.PunitarioEditFieldLabel = uilabel(app.UIFigure);
            app.PunitarioEditFieldLabel.Text = 'Punitario';
            app.txtpunitario = uieditfield(app.UIFigure, 'numeric');
            app.tabla = uitable(app.UIFigure);
            app.tabla.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'};
            app.tabla.RowName = {};
            app.tabla.CellEditCallback = createCallbackFcn(app, @tablaCellEdit, true);
            app.tabla.SelectionChangedFcn = createCallbackFcn(app, @tablaSelectionChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Aplicacion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end